classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Panel_5     matlab.ui.container.Panel
        Label_2     matlab.ui.control.Label
        Label       matlab.ui.control.Label
        Panel_2     matlab.ui.container.Panel
        Panel_6     matlab.ui.container.Panel
        Label_4     matlab.ui.control.Label
        Label_3     matlab.ui.control.Label
        Panel_4     matlab.ui.container.Panel
        LEditField  matlab.ui.control.NumericEditField
        LLabel      matlab.ui.control.Label
        Panel_3     matlab.ui.container.Panel
        Label_5     matlab.ui.control.Label
        RLabel      matlab.ui.control.Label
        Panel       matlab.ui.container.Panel
        Button_3    matlab.ui.control.Button
        Button_2    matlab.ui.control.Button
        Button      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            editTextInput = app.LEditField.Value;
            [compressed, original, r] = main(editTextInput);
            app.Label_2.Text = compressed;
            app.Label_4.Text = original;
            app.Label_5.Text = r;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            filePath = 'encoded_huffman_data.txt';
    
            % Запрашиваем у пользователя папку для сохранения файла
            destinationFolder = uigetdir('Выберите папку для сохранения файла');
            
            % Если пользователь выбрал папку
            if destinationFolder ~= 0
                % Создаем полный путь к файлу в выбранной папке
                destinationFilePath = fullfile(destinationFolder, 'encoded_huffman_data.txt');
            
                % Копируем файл в выбранную папку
                copyfile(filePath, destinationFilePath);
                disp('Файл успешно скачан.');
            else
                disp('Выбор папки отменен.');
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            filePath = 'decoded_data.txt';
    
            % Запрашиваем у пользователя папку для сохранения файла
            destinationFolder = uigetdir('Выберите папку для сохранения файла');
            
            % Если пользователь выбрал папку
            if destinationFolder ~= 0
                % Создаем полный путь к файлу в выбранной папке
                destinationFilePath = fullfile(destinationFolder, 'decoded_data.txt');
            
                % Копируем файл в выбранную папку
                copyfile(filePath, destinationFilePath);
                disp('Файл успешно скачан.');
            else
                disp('Выбор папки отменен.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Сгенерировать тект';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Закрдировать текст';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = 'Разкодировать текст';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_3 = uipanel(app.Panel_2);
            app.RLabel = uilabel(app.Panel_3);
            app.RLabel.Text = ' R =';
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = '';
            app.Panel_4 = uipanel(app.Panel_2);
            app.LLabel = uilabel(app.Panel_4);
            app.LLabel.Text = 'L =          ';
            app.LEditField = uieditfield(app.Panel_4, 'numeric');
            app.LEditField.Value = 500;
            app.Panel_6 = uipanel(app.Panel_2);
            app.Label_3 = uilabel(app.Panel_6);
            app.Label_3.Text = 'Оригинальный';
            app.Label_4 = uilabel(app.Panel_6);
            app.Label_4.Text = '';
            app.Panel_5 = uipanel(app.UIFigure);
            app.Label = uilabel(app.Panel_5);
            app.Label.Text = 'Сжатый';
            app.Label_2 = uilabel(app.Panel_5);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end